classdef wordGameSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WordGameSolverUIFigure  matlab.ui.Figure
        Copyright2022TheMathWorksIncLabel  matlab.ui.control.Label
        Restart                 matlab.ui.control.Button
        SuggestionList          matlab.ui.control.ListBox
        ShowSuggestionsButton   matlab.ui.control.Button
        Gray                    matlab.ui.control.Button
        Yellow                  matlab.ui.control.Button
        Green                   matlab.ui.control.Button
    end

    % Copyright 2022 The MathWorks, Inc.

    properties (Access = private)
        CurrentLetter % Currently selected letter
        CurrentColor % Currently selected color
        AllLetters;
        AllWords;
        CurrentRow;
        CurrentColumn;
        ToExclude;
        ToInclude;
        CorrectPosition;
    end
    
    methods (Access = private)
        
        function LoadWords(app)
            % Load the words from a mat file dictionary
            load words.mat words
            app.AllWords = words;
        end

        % This is the callback which is called when clicking on a letter
        % button
        function SelectLetter(app,src,event)
            % store the current color, so it can restore when the current
            % letter is unselected
            if ~isempty(app.CurrentLetter)
                app.CurrentLetter.BackgroundColor = app.CurrentColor;
            end

            % highlight the selected letter
            app.HighlighLetter(event.Source);

            % set the focus to the UI figure, so it keep listening for keys
            app.WordGameSolverUIFigure.focus();
        end

        function UpdateSolutions(app)
            app.SuggestionList.Items = {};
            solutions = app.GetSolutions();
            for i=1:size(solutions,1)
                app.SuggestionList.Items =  [app.SuggestionList.Items {solutions(i,:)}];
            end
        end
       
        % compute all available words based on the status of the letters on
        % the board
        function solutions = GetSolutions(app)
            % make sure all letters have the correct color
            app.ResetHighlight();

            % update all information to be used in the algorithm that
            % select the valid words
            app.UpdateStatusData();
            validChar = app.SelectValidChars();
            
            % select all valid words
            solutions = [];
            for i=1:length(app.AllWords)

                % for each character check if the word is compatible
                compatibleWord = true;
                for j=1:5

                    % interrupt the loop if the word is not compatible
                    if (compatibleWord == false) 
                        continue;
                    end

                    % check compatibility with the known letters in the
                    % right or wrong position
                    if app.CorrectPosition(j) == '-'
                        
                        % if the character is invalid, then mark the word
                        % as incompatible
                        foundInvalidChar = find(validChar == app.AllWords(i,j), 1);
                        if isempty(foundInvalidChar)
                            compatibleWord = false;
                        end

                        % if the character is valid, but it is in an
                        % invalid position, then mark the word as
                        % incompatible
                        if app.IncompatiblePosition(app.AllWords(i,j), j)
                            compatibleWord = false;
                            continue;
                        end
                    elseif app.CorrectPosition(j) ~= app.AllWords(i,j)
                        % check if the caracter in compatible with the
                        % known characters in correct positions
                        compatibleWord = false;
                    end
                end

                % skip the next step if the word was identified as
                % incompatible
                if ~compatibleWord
                    continue;
                end

                % make sure that the word contains all the charactes that
                % are known to be included - this narrow the number of
                % possible solutions and allow to converge quicker
                for j=1:length(app.ToInclude)
                    foundValidChar = find(app.AllWords(i,:) == app.ToInclude(j), 1);
                    if isempty(foundValidChar)
                        compatibleWord = false;
                    end
                end

                % add compatible words to the solution array
                if (compatibleWord)
                    solutions = [solutions; app.AllWords(i,:)];
                end
            end
        end

        % remove the invalid characters from the list, to use when finind
        % the solution
        function validChar = SelectValidChars(app)
            allChar = 'QWERTYUIOPASDFGHJKLZXCVBNM';
            validChar = [];
            for i=1:length(allChar)
                foundLetter = find(app.ToExclude == allChar(i));
                if isempty(foundLetter)
                    validChar = [validChar allChar(i)];
                end
            end
        end

        % update all data, based on the current status of all letters on
        % the board
        function UpdateStatusData(app)
            app.ToInclude = '';
            app.ToExclude = '';
            app.CorrectPosition = '-----';
            for i=1:length(app.AllLetters)
                for j=1:5
                    currentletter = app.AllLetters{i,j};
                    if isempty(currentletter.Text) 
                        continue;
                    end
                    bkcolor = app.AllLetters{i,j}.BackgroundColor;
                    if (bkcolor == app.Gray.BackgroundColor)
                        app.ToExclude = [app.ToExclude currentletter.Text];
                    elseif (bkcolor == app.Yellow.BackgroundColor)
                        app.ToInclude = [app.ToInclude currentletter.Text];
                    else
                        [~, column] = app.GetLetterPosition(currentletter);
                        app.CorrectPosition(column) = currentletter.Text;
                    end
                end
            end        
        end

        % given a character in a specific position, check if this caracter
        % is compatible with all the known words that are in the incorrect
        % position, but looking at all the letters on the board
        function incompatible = IncompatiblePosition(app, letter, column)
            for i=1:length(app.AllLetters)

                % skip empty letters on the board
                if isempty(app.AllLetters{i, column}.Text)
                    continue;
                end

                % check if the letter has been identified as incompatible
                % with the provided position
                letterMatch = app.AllLetters{i, column}.Text == letter;
                isYellow = isequal(app.AllLetters{i, column}.BackgroundColor, app.Yellow.BackgroundColor);
                if letterMatch && isYellow
                    incompatible = true;
                    return;
                end
            end
            incompatible = false;
        end

        % copy the status of the letters in the current row by propagating
        % the known status information
        function CopyStatus(app, row, column)
            letter = app.AllLetters{row, column};

            % if this is an empty letter make it gray
            if isempty(letter.Text)
                letter.BackgroundColor = app.Gray.BackgroundColor;
                letter.Tooltip = '';
                return;
            end

            if (app.CorrectPosition(column) == letter.Text)
                % if the letter is in the correct position, mark it green
                letter.BackgroundColor = app.Green.BackgroundColor;
                letter.Tooltip = app.Green.Tooltip;
            elseif ~isempty(find(app.ToInclude == letter.Text, 1))
                % if the letter is corrent, but not in the right position,
                % mark it yellow
                letter.BackgroundColor = app.Yellow.BackgroundColor;
                letter.Tooltip = app.Yellow.Tooltip;
            end
        end
    
        % given a letter button, return its row and column position
        function [row, column] = GetLetterPosition(app, letter)
            for i=1:length(app.AllLetters)
                for j=1:5
                    if (app.AllLetters{i,j} == letter)
                        row = i;
                        column = j;
                        return;
                    end
                end
            end
        end

        % find the first empty row to insert the new suggestion
        function row = GetEmptyRow(app)
            for i=1:length(app.AllLetters)
                for j=1:5
                    if isempty(app.AllLetters{i,j}.Text)
                        row = i;
                        return;
                    end
                end
            end
        end

        % highlight the given letter, and restore the state of the previous
        % highlighted one
        function HighlighLetter(app, letter)
            % restore the color
            app.ResetHighlight();

            % update the current value
            app.CurrentColor = letter.BackgroundColor;
            app.CurrentLetter = letter;
            app.CurrentLetter.BackgroundColor = [1 1 1];
            app.CurrentLetter.FontColor = [0 0 0];

            [app.CurrentRow, app.CurrentColumn] = app.GetLetterPosition(letter);
        end

        % reset the highligh of the current letter
        function ResetHighlight(app)
            app.CurrentLetter.BackgroundColor = app.CurrentColor;
            app.CurrentLetter.FontColor = [1 1 1];
        end
        
        % move the highligh to the next letter in the board
        function MoveToNextLetter(app)
            if (app.CurrentColumn < 5)
                app.CurrentColumn = app.CurrentColumn + 1;
            else
                app.CurrentColumn = 1;
                app.CurrentRow = app.CurrentRow + 1;
            end
            app.HighlighLetter(app.AllLetters{app.CurrentRow, app.CurrentColumn});
        end

        function ShowProgress(app)
            d = uiprogressdlg(app.WordGameSolverUIFigure,'Title','Computing SVD',...
                'Indeterminate','on');
            drawnow
            
            % Do the SVD computation
            svd(rand(5000));
        
            % close the dialog box
            close(d)
        end

        function Initialize(app)

            % initialize private properties
            app.CorrectPosition = '-----';
            app.CurrentRow = 1;
            app.CurrentColumn = 1;
            app.CurrentColor = app.Gray.BackgroundColor;
            app.CurrentLetter = app.AllLetters{1,1};

            % load all words
            app.LoadWords;
            app.UpdateSolutions();

            % focus the window, so I can start typing
            app.WordGameSolverUIFigure.focus();
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % adding all the button for the letters
            app.AllLetters = {};
            for i=1:6
                for j=1:5
                    b = uibutton(app.WordGameSolverUIFigure);
                    b.Text = "";
                    b.BackgroundColor = app.Gray.BackgroundColor;
                    b.FontColor = [1 1 1];
                    b.FontWeight = 'bold';
                    b.ButtonPushedFcn = @app.SelectLetter;
                    b.Position = [30 + 30*j 270 - 30*i 25 25];
                    app.AllLetters{i,j} = b;
                end
            end

            app.Initialize();
        end

        % Callback function
        function LetterincorrectpositionMenuSelected(app, event)

        end

        % Callback function
        function LetterSelected(app, event)

        end

        % Key press function: WordGameSolverUIFigure
        function WordGameSolverUIFigureKeyPress(app, event)
            key = event.Key;

            if isequal(key, 'backspace') || isequal(key, 'delete')
                key = '';
            end

            if isempty(key) || (length(key) == 1 && key >= 'a' && key <= 'z')
                app.AllLetters{app.CurrentRow, app.CurrentColumn}.Text = upper(key);
                row = app.CurrentRow;
                column = app.CurrentColumn;
    
                app.MoveToNextLetter();
    
                app.UpdateStatusData();
                app.CopyStatus(row, column);            
            end
        end

        % Button pushed function: Gray, Green, Yellow
        function StatusButtonPushed(app, event)
            app.CurrentLetter.BackgroundColor = event.Source.BackgroundColor;
            app.CurrentColor = app.CurrentLetter.BackgroundColor;
            app.CurrentLetter.Tooltip = event.Source.Tooltip;
            app.MoveToNextLetter();
        end

        % Button pushed function: Restart
        function RestartButton(app, event)
            % show a progress dialog
            d = uiprogressdlg(app.WordGameSolverUIFigure,'Title','Restarting',...
                'Indeterminate','on');
            drawnow
                    
            % close the dialog box
            for i=1:length(app.AllLetters)
                for j=1:5
                    app.AllLetters{i,j}.Text = "";
                    app.AllLetters{i,j}.BackgroundColor = app.Gray.BackgroundColor;
                end
            end
            app.Initialize();

            % close the progress dialog
            close(d)
        end

        % Button pushed function: ShowSuggestionsButton
        function ShowSuggestionsButtonPushed(app, event)
            app.UpdateSolutions();
        end

        % Value changed function: SuggestionList
        function SuggestionListValueChanged(app, event)
            emptyRow = app.GetEmptyRow();
            value = app.SuggestionList.Value;
            app.UpdateStatusData();
            for i=1:5
                app.AllLetters{emptyRow,i}.Text = value(i);
                app.CopyStatus(emptyRow, i);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WordGameSolverUIFigure = uifigure('Visible', 'off');
            app.WordGameSolverUIFigure.Name = 'Word Game Solver';
            app.WordGameSolverUIFigure.KeyPressFcn = createCallbackFcn(app, @WordGameSolverUIFigureKeyPress, true);
            app.Green = uibutton(app.WordGameSolverUIFigure, 'push');
            app.Green.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Green.Tooltip = {'The letter is in the word and in the correct spot.'};
            app.Green.Text = '';
            app.Yellow = uibutton(app.WordGameSolverUIFigure, 'push');
            app.Yellow.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Yellow.Tooltip = {'The letter is in the word but in the wrong spot.'};
            app.Yellow.Text = '';
            app.Gray = uibutton(app.WordGameSolverUIFigure, 'push');
            app.Gray.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Gray.Tooltip = {'The letter is not in the word in any spot.'};
            app.Gray.Text = '';
            app.ShowSuggestionsButton = uibutton(app.WordGameSolverUIFigure, 'push');
            app.ShowSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSuggestionsButtonPushed, true);
            app.ShowSuggestionsButton.Text = 'UPDATE SUGGESTIONS';
            app.SuggestionList = uilistbox(app.WordGameSolverUIFigure);
            app.SuggestionList.Items = {};
            app.SuggestionList.ValueChangedFcn = createCallbackFcn(app, @SuggestionListValueChanged, true);
            app.SuggestionList.Value = {};
            app.Restart = uibutton(app.WordGameSolverUIFigure, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButton, true);
            app.Restart.Icon = fullfile(pathToMLAPP, 'revert.png');
            app.Restart.Text = '';
            app.Copyright2022TheMathWorksIncLabel = uilabel(app.WordGameSolverUIFigure);
            app.Copyright2022TheMathWorksIncLabel.Text = 'Copyright 2022 The MathWorks, Inc.';
            % Show the figure after all components are created
            app.WordGameSolverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wordGameSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WordGameSolverUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WordGameSolverUIFigure)
        end
    end
end